classdef SigViewertest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button                  matlab.ui.control.Button
        AmplitudeDropDown       matlab.ui.control.DropDown
        AmplitudeDropDownLabel  matlab.ui.control.Label
        ImpedanceButton         matlab.ui.control.StateButton
        ConnectButton           matlab.ui.control.StateButton
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        b                   % 串口设备
        EMG_CHANNEL
        emg_cnt_max
        EMG_bytes           % 记录一帧的字节数据
        emg_cnt_state       % 记录switch函数的状态
        emg_cnt_count       % 每次计数到emg_cnt_max为止
        emg_idx
        EMG_frame           % 数据解析后的数据帧
        result_emg_idx
        XL
        line_EMG
        Ifrun               % while是否运行
        Offset              % 通道间 偏移
        testa
        testb
    end
    
    properties (Access = public)
        result_emg          % 绘图更新缓冲区，refreshdata处要求public属性
    end
    
    methods (Access = private)
        
        function EEGSig(app)
            while app.Ifrun
                buff=read(app.b,660,'uint8');
                for index=1:length(buff)
                    switch(app.emg_cnt_state)
                        case 0
                            if(buff(index)==187) %0xBB
                                app.emg_cnt_state=1;
                            else
                                app.emg_cnt_state=0;
                            end
                        case 1
                            if(buff(index)==170) %0xAA
                                app.emg_cnt_state=2;
                                app.emg_cnt_count=1;
                                emg_sumchkm = 0;
                            elseif(buff(index)==187)%排除0xBB BB AA的情况
                                app.emg_cnt_state=1;
                            else
                                app.emg_cnt_state=0;
                            end
                        case 2
                            app.EMG_bytes(1,app.emg_cnt_count) = buff(index);
                            app.emg_cnt_count = app.emg_cnt_count + 1;
                            emg_sumchkm = emg_sumchkm + buff(index);
                            if(app.emg_cnt_count == app.emg_cnt_max+1)%%判断数据接收是否结束
                                app.emg_cnt_state=3;
                            else
                                app.emg_cnt_state=2;
                            end
                        case 3
                            if(buff(index) == 0)%%检查校验位
                                app.emg_cnt_state=4;
                            else
                                app.emg_cnt_state=0;
                                disp(app.emg_idx)%%校验错误,打印当前帧位置和数据
                            end
                        case 4
                            EMG_Sequence(app.emg_idx,1) = buff(index);%%%记录包序号

                            % 原始数据解析%

                            for i=1:9
                                for j=1:8
                                    if(app.EMG_bytes(1,j*3-2+24*(i-1)) > 127)%%%第8位是否为1
                                        app.EMG_frame(i,j) = swapbytes(typecast(uint8([255 app.EMG_bytes((j*3-2+24*(i-1)):j*3+24*(i-1))]),'int32'));
                                    else
                                        app.EMG_frame(i,j) = swapbytes(typecast(uint8([0   app.EMG_bytes((j*3-2+24*(i-1)):j*3+24*(i-1))]),'int32'));
                                    end
                                end
                            end
                            app.emg_idx = app.emg_idx + 1;
                            app.emg_cnt_state=0;  % switch状态切换%
                            % 绘图缓存填充%
                            app.result_emg((app.result_emg_idx-1)*9+1:app.result_emg_idx*9,:)=app.EMG_frame*0.02235174 + app.testb*app.Offset;
                            app.result_emg_idx=mod(app.result_emg_idx,278)+1;

                    end
                end
                %%刷新图像
                refreshdata(app.line_EMG,'caller');
                app.XL.Value = app.result_emg_idx*9;
                drawnow limitrate 
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EMG_CHANNEL=8;
            app.emg_cnt_max=app.EMG_CHANNEL*3*9;
            app.EMG_bytes=zeros(1,app.emg_cnt_max);%%%记录一帧的字节数据
            app.emg_cnt_state=0;%%%记录switch函数的状态
            app.emg_cnt_count=0;%%%每次计数到emg_cnt_max为止
            app.emg_idx=1;
            app.EMG_frame=zeros(9,app.EMG_CHANNEL);%%%数据解析后的数据帧
            app.result_emg=zeros(2502,app.EMG_CHANNEL);%%%绘图更新缓冲区
            app.result_emg_idx=1;
            
            app.Offset=100;
            app.testa=[1 2 3 4 5 6 7 8];
            app.testb=zeros(9,8);
            app.testb = app.testb + app.testa;

            %设置图的数据源
            T=['app.result_emg(:,  )'];
            for Z=1:8
                app.line_EMG{Z}=plot(app.UIAxes,app.result_emg(:,Z));
                if Z<10
                    T(end-1)=string(Z);
                else
                    T(end-2:end-1)=string(Z);
                end
                set(app.line_EMG{Z},'YDataSource',T);  % 设置y轴数据来源 data1
                hold(app.UIAxes,"on")      % 为了让多条图线在一个图中显示
            end

            %设置垂直线
            app.XL = xline(app.UIAxes,0);
            
            app.Ifrun = false;
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            if value == 1
                delete(instrfindall);
                scom = 'COM4'; %name of serial
                Baudrate = 921600; %serial baudrate, should be same as the mcu setting
                try
                    app.b = serialport(scom,Baudrate);
                    app.ConnectButton.Text='Disconnect';
                    app.ConnectButton.BackgroundColor=[0.07 0.62 1.00];
                    disp('串口打开成功');
                catch
                    disp('串口打开失败');
                    delete(app.b);
                end
            else
                delete(app.b);
                app.ConnectButton.Text = 'Connect';
                app.ConnectButton.BackgroundColor = [0.8 0.8 0.8];
            end
        end

        % Value changed function: ImpedanceButton
        function ImpedanceButtonValueChanged(app, event)
            value = app.ImpedanceButton.Value;
            if value == 1
                write(app.b,'Z',"uint8");
                app.ImpedanceButton.Text='EEG';
                app.Ifrun = true;            
                EEGSig(app);                
            else
                write(app.b,'T',"uint8");
                app.ImpedanceButton.Text='Impedance';
                app.Ifrun = true;            
                EEGSig(app);
            end            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UIAxes.YLim=[0 5000];
        end

        % Value changed function: AmplitudeDropDown
        function AmplitudeDropDownValueChanged(app, event)
            value = app.AmplitudeDropDown.Value;
            switch value
                case '10 uV'
                    app.Offset = 20;
                    app.UIAxes.YLim=[0 160];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150];
                case '50 uV'
                    app.Offset = 100;
                    app.UIAxes.YLim=[0 800];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*5;
                case '100 uV'
                    app.Offset = 200;
                    app.UIAxes.YLim=[0 1600];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*10;
                case '200 uV'
                    app.Offset = 400;
                    app.UIAxes.YLim=[0 3200];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*20;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 2502];
            app.UIAxes.YLim = [0 1600];
            app.UIAxes.YTick = [100 200 300 400 500 600 700 800 900 1000 1100 1200 1300 1400 1500];
            app.UIAxes.YTickLabel = {'CH8'; ''; 'CH7'; ''; 'CH6'; ''; 'CH5'; ''; 'CH4'; ''; 'CH3'; ''; 'CH2'; ''; 'CH1'};
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.ImpedanceButton = uibutton(app.UIFigure, 'state');
            app.ImpedanceButton.ValueChangedFcn = createCallbackFcn(app, @ImpedanceButtonValueChanged, true);
            app.ImpedanceButton.Text = 'Impedance';
            app.AmplitudeDropDownLabel = uilabel(app.UIFigure);
            app.AmplitudeDropDownLabel.Text = 'Amplitude';
            app.AmplitudeDropDown = uidropdown(app.UIFigure);
            app.AmplitudeDropDown.Items = {'10 uV', '50 uV', '100 uV', '200 uV', '400 uV', '1 mV', '3 mV'};
            app.AmplitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @AmplitudeDropDownValueChanged, true);
            app.AmplitudeDropDown.Value = '100 uV';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SigViewertest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end